classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BlockSliderLabel     matlab.ui.control.Label
        BlockSlider          matlab.ui.control.Slider
        Lamp                 matlab.ui.control.Lamp
        MassEditFieldLabel   matlab.ui.control.Label
        MassEditField        matlab.ui.control.NumericEditField
        AcceEditFieldLabel   matlab.ui.control.Label
        AcceEditField        matlab.ui.control.NumericEditField
        CalculateButton      matlab.ui.control.Button
        ForceEditFieldLabel  matlab.ui.control.Label
        ForceEditField       matlab.ui.control.NumericEditField
        PowerSwitchLabel     matlab.ui.control.Label
        PowerSwitch          matlab.ui.control.Switch
        UIAxes               matlab.ui.control.UIAxes
        EStopButton          matlab.ui.control.StateButton
    end

    
    methods (Access = public)
        
        function state = getEStopValue(app)
            state = app.EStopButton.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BlockSlider
        function BlockSliderValueChanged(app, event)
            value = app.BlockSlider.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            mass = app.MassEditField.Value;
            acc = app.AcceEditField.Value;
            
            force = mass*acc;
            app.ForceEditField.Value = force;
        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)
            value = app.PowerSwitch.Value;
            if (value == "On")
                app.Lamp.Color = [0,1,0];
            elseif (value == "Off")
                app.Lamp.Color = [1,0,0];
            end
        end

        % Value changed function: EStopButton
        function EStopButtonValueChanged(app, event)
            value = app.EStopButton.Value;
            if (value == 1)
                disp('Stop');
            elseif (value == 0)
                disp('Continue');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlockSliderLabel = uilabel(app.UIFigure);
            app.BlockSliderLabel.Text = 'Block Slider';
            app.BlockSlider = uislider(app.UIFigure);
            app.BlockSlider.ValueChangedFcn = createCallbackFcn(app, @BlockSliderValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.MassEditFieldLabel = uilabel(app.UIFigure);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.UIFigure, 'numeric');
            app.AcceEditFieldLabel = uilabel(app.UIFigure);
            app.AcceEditFieldLabel.Text = 'Acce';
            app.AcceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ForceEditFieldLabel = uilabel(app.UIFigure);
            app.ForceEditFieldLabel.Text = 'Force';
            app.ForceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerSwitchLabel = uilabel(app.UIFigure);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.UIFigure, 'slider');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EStopButton = uibutton(app.UIFigure, 'state');
            app.EStopButton.ValueChangedFcn = createCallbackFcn(app, @EStopButtonValueChanged, true);
            app.EStopButton.Text = 'E-Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end